classdef about < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure         matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        AboutPanel            matlab.ui.container.Panel
        GridLayoutAboutPanel  matlab.ui.container.GridLayout
        UIAxesLogo            matlab.ui.control.UIAxes
        VersionLabel          matlab.ui.control.Label
        CopyrightLabel        matlab.ui.control.Label
        LicenseArt1Label      matlab.ui.control.Label
        LicenseArt2Label      matlab.ui.control.Label
        LicenseArt3Label      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % ---------------------------------------------------------
            % Center the GUI in the middle of the main app
            pos = MainApp.CicadaUIFigure.Position;
            h = app.AboutUIFigure.Position(3);
            w = app.AboutUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.AboutUIFigure.Position = [x y h w];
            app.AboutUIFigure.Resize = 'off';
            
            % ---------------------------------------------------------
            % Load the Cicada Logo
            app.UIAxesLogo.UserData.ImObj = imagesc(app.UIAxesLogo, 'CData', cicadaLogo());
            app.UIAxesLogo.YDir = 'reverse';
            app.UIAxesLogo.Toolbar = [];
            app.UIAxesLogo.Interactions = [];
            
            % ---------------------------------------------------------
            % Load the Software Version
            app.VersionLabel.Text = ['Version ', cic_version()];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.GridLayout = uigridlayout(app.AboutUIFigure);
            app.AboutPanel = uipanel(app.GridLayout);
            app.AboutPanel.Title = 'About';
            app.GridLayoutAboutPanel = uigridlayout(app.AboutPanel);
            app.UIAxesLogo = uiaxes(app.GridLayoutAboutPanel);
            title(app.UIAxesLogo, '')
            xlabel(app.UIAxesLogo, '')
            ylabel(app.UIAxesLogo, '')
            app.UIAxesLogo.XTick = [];
            app.UIAxesLogo.YTick = [];
            app.UIAxesLogo.ZTick = [];
            app.UIAxesLogo.Visible = 'off';
            app.VersionLabel = uilabel(app.GridLayoutAboutPanel);
            app.VersionLabel.Text = '';
            app.CopyrightLabel = uilabel(app.GridLayoutAboutPanel);
            app.CopyrightLabel.Text = 'Copyright (c) 2019, Rick Wassing, rickwassing@gmail.com';
            app.LicenseArt1Label = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt1Label.Text = {'This program is free software; you can redistribute it and/or'; 'modify it under the terms of the GNU General Public License'; 'as published by the Free Software Foundation; either version 3'; 'of the License, or any later version.'};
            app.LicenseArt2Label = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label.Text = {'This program is distributed in the hope that it will be useful,'; 'but without any warranty; without even the implied warranty of'; 'merchantability or fitness for a particular purpose.  See the'; 'GNU General Public License for more details.'};
            app.LicenseArt3Label = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt3Label.Text = {'You can find the GNU General License at'; 'http://www.gnu.org/licenses'};
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end