classdef createRelativeEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateRelativeEventUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        CreateNewEventPanel            matlab.ui.container.Panel
        GridLayoutCreateNewEventPanel  matlab.ui.container.GridLayout
        RefEventLabel                  matlab.ui.control.Label
        RefEventTypeLabel              matlab.ui.control.Label
        RelativeToLabel                matlab.ui.control.Label
        NewEventLabel                  matlab.ui.control.Label
        DelayLabel                     matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        ReferenceEventLabelInput       matlab.ui.control.DropDown
        ReferenceEventTypeInput        matlab.ui.control.DropDown
        RelativeButtonGroup            matlab.ui.container.ButtonGroup
        OnsetButton                    matlab.ui.control.RadioButton
        OffsetButton                   matlab.ui.control.RadioButton
        EventLabelInput                matlab.ui.control.EditField
        DelayPanel                     matlab.ui.container.Panel
        GridLayoutDelayPanel           matlab.ui.container.GridLayout
        DelayHourUpButton              matlab.ui.control.Button
        DelayMinuteUpButton            matlab.ui.control.Button
        DelayHourInput                 matlab.ui.control.NumericEditField
        DelayHourLabel                 matlab.ui.control.Label
        DelayMinuteInput               matlab.ui.control.NumericEditField
        DelayMinuteLabel               matlab.ui.control.Label
        DelayHourDownButton            matlab.ui.control.Button
        DelayMinuteDownButton          matlab.ui.control.Button
        DelaySignButton                matlab.ui.control.StateButton
        DurationPanel                  matlab.ui.container.Panel
        GridLayoutDurationPanel        matlab.ui.container.GridLayout
        DurationHourUpButton           matlab.ui.control.Button
        DurationMinuteUpButton         matlab.ui.control.Button
        DurationHourDownButton         matlab.ui.control.Button
        DurationMinuteDownButton       matlab.ui.control.Button
        DurationHourLabel              matlab.ui.control.Label
        DurationHourInput              matlab.ui.control.NumericEditField
        DurationMinuteInput            matlab.ui.control.NumericEditField
        DurationMinuteLabel            matlab.ui.control.Label
        ProprietaryPanel               matlab.ui.container.Panel
        GridLayoutProprietaryPanel     matlab.ui.container.GridLayout
        DismissProprietaryPanel        matlab.ui.control.Button
        ProprietaryLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
        function setDateInputs(~, obj, dateNumber, formatOut)
            obj.Value = datestr(dateNumber, formatOut);
        end
        function enableDisableButtons(app)
            
            if app.MainApp.Import.Data.duration - 60 < 1
                app.DurationHourDownButton.Enable = 'off';
            else
                app.DurationHourDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.duration - 1 < 1
                app.DurationMinuteDownButton.Enable = 'off';
            else
                app.DurationMinuteDownButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.CreateRelativeEventUIFigure.Position(3);
            w = app.CreateRelativeEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.CreateRelativeEventUIFigure.Position = [x y h w];
            app.CreateRelativeEventUIFigure.Resize = 'off';
            
            % Populate the event label input
            labels = unique(app.MainApp.ACT.analysis.events.label);
            labels(strcmpi(labels, 'start')) = [];
            app.ReferenceEventLabelInput.Items = [{'Select...'}; labels];
            
            app.EventLabelInput.Tag = 'EventLabel';
            
            % Set the UserData of the inputs
            app.DelayHourInput.UserData.fname = 'delay';
            app.DelayMinuteInput.UserData.fname = 'delay';
            app.DurationHourInput.UserData.fname = 'duration';
            app.DurationMinuteInput.UserData.fname = 'duration';
            
            % Set the UserData of the buttons
            app.DelayHourUpButton.UserData.fname = 'delay';
            app.DelayHourUpButton.UserData.step = 60;
            app.DelayHourUpButton.UserData.direction = 1;
            app.DelayMinuteUpButton.UserData.fname = 'delay';
            app.DelayMinuteUpButton.UserData.step = 1;
            app.DelayMinuteUpButton.UserData.direction = 1;
            
            app.DelayHourDownButton.UserData.fname = 'delay';
            app.DelayHourDownButton.UserData.step = 60;
            app.DelayHourDownButton.UserData.direction = -1;
            app.DelayMinuteDownButton.UserData.fname = 'delay';
            app.DelayMinuteDownButton.UserData.step = 1;
            app.DelayMinuteDownButton.UserData.direction = -1;
            
            app.DurationHourUpButton.UserData.fname = 'duration';
            app.DurationHourUpButton.UserData.step = 60;
            app.DurationHourUpButton.UserData.direction = 1;
            app.DurationMinuteUpButton.UserData.fname = 'duration';
            app.DurationMinuteUpButton.UserData.step = 1;
            app.DurationMinuteUpButton.UserData.direction = 1;
            
            app.DurationHourDownButton.UserData.fname = 'duration';
            app.DurationHourDownButton.UserData.step = 60;
            app.DurationHourDownButton.UserData.direction = -1;
            app.DurationMinuteDownButton.UserData.fname = 'duration';
            app.DurationMinuteDownButton.UserData.step = 1;
            app.DurationMinuteDownButton.UserData.direction = -1;
            
            app.MainApp.Import.Data.delay = 0;
            app.MainApp.Import.Data.duration = 60;
            app.MainApp.Import.Data.label = 'Custom Event';
            
            enableDisableButtons(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data.ref = lower(app.RelativeButtonGroup.SelectedObject.Text);
            app.MainApp.Import.Data.refLabel = app.ReferenceEventLabelInput.Value;
            app.MainApp.Import.Data.refType  = app.ReferenceEventTypeInput.Value;
            app.MainApp.Import.Data.delay = app.MainApp.Import.Data.delay/60; % delay in hours
            app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration/60; % duration in hours
            app.MainApp.Import.Data.newLabel = app.EventLabelInput.Value;
            
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ReferenceEventLabelInput
        function ReferenceEventInputValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            eventLabel = event.Source.Value;
            % Populate the type input and set the value to 'Select...'
            idx = strcmpi(app.MainApp.ACT.analysis.events.label, eventLabel);
            types = unique(app.MainApp.ACT.analysis.events.type(idx));
            app.ReferenceEventTypeInput.Value = 'Select...';
            app.ReferenceEventTypeInput.Items = [{'Select...'}; types];
            app.CreateButton.Enable = 'off';
        end

        % Value changed function: ReferenceEventTypeInput
        function ReferenceEventTypeInputValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            app.CreateButton.Enable = 'on';
        end

        % Button pushed function: DelayHourDownButton, 
        % DelayHourUpButton, DelayMinuteDownButton, 
        % DelayMinuteUpButton, DurationHourDownButton, 
        % DurationHourUpButton, DurationMinuteDownButton, 
        % DurationMinuteUpButton
        function ButtonPushed(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            fname = event.Source.UserData.fname;
            step  = event.Source.UserData.step;
            dir   = event.Source.UserData.direction;
            val   = app.MainApp.Import.Data.(fname) + dir*step;
            app.MainApp.Import.Data.(fname) = val;
            % Get sign and absolute the value.
            sgn   = sign(val);
            val   = abs(val);
            
            HH = floor(val/60);
            MM = val - HH*60;
            
            % Update the input fields
            if strcmpi(fname, 'delay')
                app.DelayHourInput.Value = HH;
                app.DelayMinuteInput.Value = MM;
                app.DelaySignButton.Text = ifelse(sgn >= 0, '+', '-');
                app.DelaySignButton.BackgroundColor = ifelse(sgn >= 0, [0.18, 0.37, 0.67], [0.90, 0.18, 0.18]);
            else
                app.DurationHourInput.Value = HH;
                app.DurationMinuteInput.Value = MM;
            end
            enableDisableButtons(app)
        end

        % Value changed function: DelayHourInput, DelayMinuteInput, 
        % DurationHourInput, DurationMinuteInput
        function InputValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            fname = event.Source.UserData.fname;
            switch fname
                case 'delay'
                    HH = app.DelayHourInput.Value;
                    MM = app.DelayMinuteInput.Value;
                    sgn = ifelse(strcmpi(app.DelaySignButton.Text, '+'), 1, -1);
                case 'duration'
                    HH = app.DurationHourInput.Value;
                    MM = app.DurationMinuteInput.Value;
                    sgn = 1;
            end
            app.MainApp.Import.Data.(fname) = sgn * (HH*60 + MM);
            enableDisableButtons(app)
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            % Check that the User did not specify a proprietary event label
            if checkProprietaryEventLabel(app.EventLabelInput.Value)
                app.ProprietaryPanel.Visible = 'on';
                app.EventLabelInput.Value = event.PreviousValue;
            else
                app.ProprietaryPanel.Visible = 'off';
                app.MainApp.Import.Data.newLabel = event.Source.Value;
            end
        end

        % Value changed function: DelaySignButton
        function DelaySignButtonValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            switch event.Source.Text
                case '+'
                    event.Source.Value = 0;
                    event.Source.Text = '-';
                    event.Source.BackgroundColor = [0.90, 0.18, 0.18];
                    app.MainApp.Import.Data.delay = -1 * abs(app.MainApp.Import.Data.delay);
                case '-'
                    event.Source.Value = 0;
                    event.Source.Text = '+';
                    event.Source.BackgroundColor = [0.18, 0.37, 0.67];
                    app.MainApp.Import.Data.delay = abs(app.MainApp.Import.Data.delay);
            end
        end

        % Button pushed function: DismissProprietaryPanel
        function DismissProprietaryPanelButtonPushed(app, event)
            app.ProprietaryPanel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateRelativeEventUIFigure = uifigure('Visible', 'off');
            app.CreateRelativeEventUIFigure.Name = 'Create Relative Event';
            app.GridLayout = uigridlayout(app.CreateRelativeEventUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.CreateNewEventPanel = uipanel(app.GridLayout);
            app.CreateNewEventPanel.Title = 'Create New Event Relative To Existing Event';
            app.GridLayoutCreateNewEventPanel = uigridlayout(app.CreateNewEventPanel);
            app.RefEventLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RefEventLabel.Text = 'Ref Event Label';
            app.RefEventTypeLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RefEventTypeLabel.Text = 'Ref Event Type';
            app.RelativeToLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RelativeToLabel.Text = 'Relative To';
            app.NewEventLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.NewEventLabel.Text = 'New Event Label';
            app.DelayLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.DelayLabel.Text = 'Delay';
            app.DurationLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.DurationLabel.Text = 'Duration';
            app.ReferenceEventLabelInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.ReferenceEventLabelInput.Items = {'Select...'};
            app.ReferenceEventLabelInput.ValueChangedFcn = createCallbackFcn(app, @ReferenceEventInputValueChanged, true);
            app.ReferenceEventLabelInput.Value = 'Select...';
            app.ReferenceEventTypeInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.ReferenceEventTypeInput.Items = {'Select...'};
            app.ReferenceEventTypeInput.ValueChangedFcn = createCallbackFcn(app, @ReferenceEventTypeInputValueChanged, true);
            app.ReferenceEventTypeInput.Value = 'Select...';
            app.RelativeButtonGroup = uibuttongroup(app.GridLayoutCreateNewEventPanel);
            app.OnsetButton = uiradiobutton(app.RelativeButtonGroup);
            app.OnsetButton.Text = 'Onset';
            app.OnsetButton.Value = true;
            app.OffsetButton = uiradiobutton(app.RelativeButtonGroup);
            app.OffsetButton.Text = 'Offset';
            app.EventLabelInput = uieditfield(app.GridLayoutCreateNewEventPanel, 'text');
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Tooltip = {''};
            app.EventLabelInput.Value = 'Custom Event';
            app.DelayPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDelayPanel = uigridlayout(app.DelayPanel);
            app.DelayHourUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayHourUpButton.Text = '^';
            app.DelayMinuteUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayMinuteUpButton.Text = '^';
            app.DelayHourInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.DelayHourInput.Limits = [0 Inf];
            app.DelayHourInput.RoundFractionalValues = 'on';
            app.DelayHourInput.ValueDisplayFormat = '%i';
            app.DelayHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DelayHourLabel = uilabel(app.GridLayoutDelayPanel);
            app.DelayHourLabel.Text = 'h';
            app.DelayMinuteInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.DelayMinuteInput.Limits = [0 Inf];
            app.DelayMinuteInput.RoundFractionalValues = 'on';
            app.DelayMinuteInput.ValueDisplayFormat = '%i';
            app.DelayMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DelayMinuteLabel = uilabel(app.GridLayoutDelayPanel);
            app.DelayMinuteLabel.Text = 'm';
            app.DelayHourDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayHourDownButton.Text = 'v';
            app.DelayMinuteDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayMinuteDownButton.Text = 'v';
            app.DelaySignButton = uibutton(app.GridLayoutDelayPanel, 'state');
            app.DelaySignButton.ValueChangedFcn = createCallbackFcn(app, @DelaySignButtonValueChanged, true);
            app.DelaySignButton.Text = '+';
            app.DurationPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDurationPanel = uigridlayout(app.DurationPanel);
            app.DurationHourUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourUpButton.Text = '^';
            app.DurationMinuteUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteUpButton.Text = '^';
            app.DurationHourDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourDownButton.Text = 'v';
            app.DurationMinuteDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteDownButton.Text = 'v';
            app.DurationHourLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationHourLabel.Text = 'h';
            app.DurationHourInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationHourInput.Limits = [0 Inf];
            app.DurationHourInput.RoundFractionalValues = 'on';
            app.DurationHourInput.ValueDisplayFormat = '%i';
            app.DurationHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationHourInput.Value = 1;
            app.DurationMinuteInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationMinuteInput.Limits = [0 Inf];
            app.DurationMinuteInput.RoundFractionalValues = 'on';
            app.DurationMinuteInput.ValueDisplayFormat = '%i';
            app.DurationMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationMinuteLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationMinuteLabel.Text = 'm';
            app.ProprietaryPanel = uipanel(app.CreateRelativeEventUIFigure);
            app.ProprietaryPanel.Visible = 'off';
            app.GridLayoutProprietaryPanel = uigridlayout(app.ProprietaryPanel);
            app.DismissProprietaryPanel = uibutton(app.GridLayoutProprietaryPanel, 'push');
            app.DismissProprietaryPanel.ButtonPushedFcn = createCallbackFcn(app, @DismissProprietaryPanelButtonPushed, true);
            app.DismissProprietaryPanel.Text = 'ok';
            app.ProprietaryLabel = uilabel(app.GridLayoutProprietaryPanel);
            app.ProprietaryLabel.Text = {'Can''t use Cicada''s'; 'proprietary labels'};
            % Show the figure after all components are created
            app.CreateRelativeEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createRelativeEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateRelativeEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateRelativeEventUIFigure)
        end
    end
end