classdef editEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditEventUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        EditButton                     matlab.ui.control.Button
        ModifyEventLabelPanel          matlab.ui.container.Panel
        GridLayoutCreateNewEventPanel  matlab.ui.container.GridLayout
        EventLabelInput                matlab.ui.control.DropDown
        CustomEventLabelInput          matlab.ui.control.EditField
        DataPanel                      matlab.ui.container.Panel
        ModifyEventOnsetPanel          matlab.ui.container.Panel
        GridLayoutEditEventOnsetPanel  matlab.ui.container.GridLayout
        OnsetDayLabel                  matlab.ui.control.Label
        OnsetMonthLabel                matlab.ui.control.Label
        OnsetYearLabel                 matlab.ui.control.Label
        OnsetHourLabel                 matlab.ui.control.Label
        OnsetMinuteLabel               matlab.ui.control.Label
        ButtonOnsetDayUp               matlab.ui.control.Button
        ButtonOnsetMonthUp             matlab.ui.control.Button
        ButtonOnsetYearUp              matlab.ui.control.Button
        ButtonOnsetHourUp              matlab.ui.control.Button
        ButtonOnsetMinuteUp            matlab.ui.control.Button
        InputOnsetDay                  matlab.ui.control.EditField
        InputOnsetMonth                matlab.ui.control.EditField
        InputOnsetYear                 matlab.ui.control.EditField
        InputOnsetHour                 matlab.ui.control.EditField
        InputOnsetMinute               matlab.ui.control.EditField
        ButtonOnsetDayDown             matlab.ui.control.Button
        ButtonOnsetMonthDown           matlab.ui.control.Button
        ButtonOnsetYearDown            matlab.ui.control.Button
        ButtonOnsetHourDown            matlab.ui.control.Button
        ButtonOnsetMinuteDown          matlab.ui.control.Button
        LabelOnsetDaySep               matlab.ui.control.Label
        LabelOnsetMonthSep             matlab.ui.control.Label
        LabelOnsetHourSep              matlab.ui.control.Label
        ModifyEventDurationPanel       matlab.ui.container.Panel
        GridLayoutEditEventDurationPanel  matlab.ui.container.GridLayout
        DurationHourLabel              matlab.ui.control.Label
        ButtonDurationHourUp           matlab.ui.control.Button
        ButtonDurationMinuteUp         matlab.ui.control.Button
        InputDurationHour              matlab.ui.control.EditField
        InputDurationMinute            matlab.ui.control.EditField
        ButtonDurationHourDown         matlab.ui.control.Button
        ButtonDurationMinuteDown       matlab.ui.control.Button
        LabelDurationHourSep           matlab.ui.control.Label
        NewOffsetLabel                 matlab.ui.control.Label
        NewEventOffsetValue            matlab.ui.control.Label
        CurrentOffsetLabel             matlab.ui.control.Label
        CurrentEventOffsetValue        matlab.ui.control.Label
        DeleteEventButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        Import;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
        function setInputs(app)
            % Extract values
            onset = app.MainApp.Import.Data.onset;
            duration = app.MainApp.Import.Data.duration;
            % Calculate hours and mintus
            HH = floor(duration/60);
            MM = round(duration-(HH*60));
            while MM >= 60
                HH = HH + 1;
                MM = MM - 60;
            end
            % Set input fields
            app.InputOnsetDay.Value = datestr(onset, 'dd');
            app.InputOnsetMonth.Value = datestr(onset, 'mm');
            app.InputOnsetYear.Value = datestr(onset, 'yyyy');
            app.InputOnsetHour.Value = datestr(onset, 'HH');
            app.InputOnsetMinute.Value = datestr(onset, 'MM');
            app.InputDurationHour.Value = num2str(HH);
            app.InputDurationMinute.Value = num2str(MM);
            % Enable/disable buttons
            app.ButtonOnsetYearDown.Enable = ifelse(addtodate(onset, -1, 'year') <= app.MainApp.ACT.xmin, 'off', 'on');
            app.ButtonOnsetYearUp.Enable = ifelse(addtodate(onset, 1, 'year') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonOnsetMonthDown.Enable = ifelse(addtodate(onset, -1, 'month') <= app.MainApp.ACT.xmin, 'off', 'on');
            app.ButtonOnsetMonthUp.Enable = ifelse(addtodate(onset, 1, 'month') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonOnsetDayDown.Enable = ifelse(addtodate(onset, -1, 'day') <= app.MainApp.ACT.xmin, 'off', 'on');
            app.ButtonOnsetDayUp.Enable = ifelse(addtodate(onset, 1, 'day') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonOnsetHourDown.Enable = ifelse(addtodate(onset, -1, 'hour') <= app.MainApp.ACT.xmin, 'off', 'on');
            app.ButtonOnsetHourUp.Enable = ifelse(addtodate(onset, 1, 'hour') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonOnsetMinuteDown.Enable = ifelse(addtodate(onset, -1, 'minute') <= app.MainApp.ACT.xmin, 'off', 'on');
            app.ButtonOnsetMinuteUp.Enable = ifelse(addtodate(onset, 1, 'minute') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonDurationHourDown.Enable = ifelse(duration-60 <= 0, 'off', 'on');
            app.ButtonDurationMinuteDown.Enable = ifelse(duration-1 <= 0, 'off', 'on');
            app.ButtonDurationMinuteUp.Enable = ifelse(addtodate(onset, 1, 'minute') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
            app.ButtonDurationHourUp.Enable = ifelse(addtodate(onset, 1, 'hour') >= app.MainApp.ACT.xmax-duration/(24*60), 'off', 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, eventID)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.EditEventUIFigure.Position(3);
            w = app.EditEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.EditEventUIFigure.Position = [x y h w];
            app.EditEventUIFigure.Resize = 'off';
            
            % Set all possible event labels to the dropdown menu
            idx = strcmpi(app.MainApp.ACT.analysis.events.type, 'customEvent');
            eventLabels = unique(app.MainApp.ACT.analysis.events.label(idx));
            if iscolumn(eventLabels)
                eventLabels = eventLabels';
            end
            app.EventLabelInput.Items = [app.EventLabelInput.Items, eventLabels];
            app.EventLabelInput.ItemsData = [app.EventLabelInput.ItemsData, eventLabels];
            
            % Initialize import data
            idx = app.MainApp.ACT.analysis.events.id == eventID;
            app.MainApp.Import.Data.delete = false;
            app.MainApp.Import.Data.id = app.MainApp.ACT.analysis.events.id(idx);
            app.MainApp.Import.Data.onset = datenum(datestr(app.MainApp.ACT.analysis.events.onset(idx), 'dd/mm/yyyy HH:MM'), 'dd/mm/yyyy HH:MM');
            app.MainApp.Import.Data.duration = round(app.MainApp.ACT.analysis.events.duration(idx)*24*60);
            app.MainApp.Import.Data.label = app.MainApp.ACT.analysis.events.label{idx};
            app.MainApp.Import.Data.type = app.MainApp.ACT.analysis.events.type{idx};
            
            % Set the input fields
            setInputs(app)
            app.EventLabelInput.Value = app.MainApp.Import.Data.label;
            app.CurrentEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            
            % Set the UserData of the buttons
            app.ButtonOnsetDayUp.UserData.field = 'onset';
            app.ButtonOnsetDayUp.UserData.step = 'day';
            app.ButtonOnsetDayUp.UserData.direction = 1;
            app.ButtonOnsetMonthUp.UserData.field = 'onset';
            app.ButtonOnsetMonthUp.UserData.step = 'month';
            app.ButtonOnsetMonthUp.UserData.direction = 1;
            app.ButtonOnsetYearUp.UserData.field = 'onset';
            app.ButtonOnsetYearUp.UserData.step = 'year';
            app.ButtonOnsetYearUp.UserData.direction = 1;
            app.ButtonOnsetHourUp.UserData.field = 'onset';
            app.ButtonOnsetHourUp.UserData.step = 'hour';
            app.ButtonOnsetHourUp.UserData.direction = 1;
            app.ButtonOnsetMinuteUp.UserData.field = 'onset';
            app.ButtonOnsetMinuteUp.UserData.step = 'minute';
            app.ButtonOnsetMinuteUp.UserData.direction = 1;
            
            app.ButtonOnsetDayDown.UserData.field = 'onset';
            app.ButtonOnsetDayDown.UserData.step = 'day';
            app.ButtonOnsetDayDown.UserData.direction = -1;
            app.ButtonOnsetMonthDown.UserData.field = 'onset';
            app.ButtonOnsetMonthDown.UserData.step = 'month';
            app.ButtonOnsetMonthDown.UserData.direction = -1;
            app.ButtonOnsetYearDown.UserData.field = 'onset';
            app.ButtonOnsetYearDown.UserData.step = 'year';
            app.ButtonOnsetYearDown.UserData.direction = -1;
            app.ButtonOnsetHourDown.UserData.field = 'onset';
            app.ButtonOnsetHourDown.UserData.step = 'hour';
            app.ButtonOnsetHourDown.UserData.direction = -1;
            app.ButtonOnsetMinuteDown.UserData.field = 'onset';
            app.ButtonOnsetMinuteDown.UserData.step = 'minute';
            app.ButtonOnsetMinuteDown.UserData.direction = -1;
            
            app.ButtonDurationHourUp.UserData.field = 'duration';
            app.ButtonDurationHourUp.UserData.step = 60;
            app.ButtonDurationHourUp.UserData.direction = 1;
            app.ButtonDurationMinuteUp.UserData.field = 'duration';
            app.ButtonDurationMinuteUp.UserData.step = 1;
            app.ButtonDurationMinuteUp.UserData.direction = 1;
            
            app.ButtonDurationHourDown.UserData.field = 'duration';
            app.ButtonDurationHourDown.UserData.step = 60;
            app.ButtonDurationHourDown.UserData.direction = -1;
            app.ButtonDurationMinuteDown.UserData.field = 'duration';
            app.ButtonDurationMinuteDown.UserData.step = 1;
            app.ButtonDurationMinuteDown.UserData.direction = -1;
            
            app.InputOnsetDay.UserData.field = 'onset';
            app.InputOnsetMonth.UserData.field = 'onset';
            app.InputOnsetYear.UserData.field = 'onset';
            app.InputOnsetHour.UserData.field = 'onset';
            app.InputOnsetMinute.UserData.field = 'onset';
            
            app.InputDurationHour.UserData.field = 'duration';
            app.InputDurationMinute.UserData.field = 'duration';
            
            % Show data excerpt in panel
            app_constructDataExcerpt(app, app.DataPanel, app.MainApp.Import.Data.onset, app.MainApp.Import.Data.onset+app.MainApp.Import.Data.duration/(24*60));
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Indicate that the import is cancelled
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: DeleteEventButton
        function DeleteEventButtonPushed(app, event)
            % Indicate that the import is good to go
            app.MainApp.Import.Return = true;
            % Indicate that the action is to delete the event
            app.MainApp.Import.Data.delete = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            % Indicate that the import is good to go
            app.MainApp.Import.Return = true;
            % Convert duration from minutes back into days
            app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration/(24*60);
            % Extract the event label and type
            switch app.EventLabelInput.Value
                case 'custom'
                    app.MainApp.Import.Data.label = app.CustomEventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
                case {'reject', 'sleepWindow'}
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'manual';
                otherwise
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
            end
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            switch event.Source.Value
                case 'select'
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'off';
                case 'custom'
                    app.CustomEventLabelInput.Visible = 'on';
                    app.EditButton.Enable = 'on';
                otherwise
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'on';
            end
        end

        % Value changed function: CustomEventLabelInput
        function CustomEventLabelInputValueChanged(app, event)
            % Check that the User did not specify a proprietary event label
            if checkProprietaryEventLabel(app.CustomEventLabelInput.Value)
                sel = uiconfirm(app.EditEventUIFigure, 'Sorry, you can''t use an event label that is already used by Cicada.', 'Notification', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'info');
                app.CustomEventLabelInput.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ButtonDurationHourDown, 
        % ButtonDurationHourUp, ButtonDurationMinuteDown, 
        % ButtonDurationMinuteUp, ButtonOnsetDayDown, 
        % ButtonOnsetDayUp, ButtonOnsetHourDown, ButtonOnsetHourUp, 
        % ButtonOnsetMinuteDown, ButtonOnsetMinuteUp, 
        % ButtonOnsetMonthDown, ButtonOnsetMonthUp, 
        % ButtonOnsetYearDown, ButtonOnsetYearUp
        function ButtonPushed(app, event)
            % Enable the edit button
            app.EditButton.Enable = ifelse(strcmpi(app.EventLabelInput.Value, 'select'), 'off', 'on');
            % Update the onset and duration
            field = event.Source.UserData.field;
            step = event.Source.UserData.step;
            dir = event.Source.UserData.direction;
            switch field
                case 'onset'
                    app.MainApp.Import.Data.onset = addtodate(app.MainApp.Import.Data.onset, dir, step);
                    app.MainApp.Import.Data.onset = min([app.MainApp.Import.Data.onset, app.MainApp.ACT.xmax-app.MainApp.Import.Data.duration/(24*60)]);
                    app.MainApp.Import.Data.onset = max([app.MainApp.Import.Data.onset, app.MainApp.ACT.xmin]);
                case 'duration'
                    app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration + step*dir;
                    app.MainApp.Import.Data.duration = min([app.MainApp.Import.Data.duration, (app.MainApp.ACT.xmax-app.MainApp.Import.Data.onset)*24*60]);
            end
            % Set the input fields
            setInputs(app)
            % Set new offset value
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            % Show data excerpt in panel
            app_constructDataExcerpt(app, app.DataPanel, app.MainApp.Import.Data.onset, app.MainApp.Import.Data.onset+app.MainApp.Import.Data.duration/(24*60));
        end

        % Value changed function: InputDurationHour, 
        % InputDurationMinute, InputOnsetDay, InputOnsetHour, 
        % InputOnsetMinute, InputOnsetMonth, InputOnsetYear
        function InputValueChanged(app, event)
            % Enable the edit button
            app.EditButton.Enable = ifelse(strcmpi(app.EventLabelInput.Value, 'select'), 'off', 'on');
            % Update the onset and duration
            field = event.Source.UserData.field;
            switch field
                case 'onset'
                    app.MainApp.Import.Data.onset = datenum([ ...
                        app.InputOnsetDay.Value, '/', ...
                        app.InputOnsetMonth.Value, '/', ...
                        app.InputOnsetYear.Value, ' ', ...
                        app.InputOnsetHour.Value, ':', ...
                        app.InputOnsetMinute.Value ...
                        ], 'dd/mm/yyyy HH:MM');
                    app.MainApp.Import.Data.onset = min([app.MainApp.Import.Data.onset, app.MainApp.ACT.xmax-app.MainApp.Import.Data.duration/(24*60)]);
                    app.MainApp.Import.Data.onset = max([app.MainApp.Import.Data.onset, app.MainApp.ACT.xmin]);
                case 'duration'
                    app.MainApp.Import.Data.duration = ...
                        str2double(app.InputDurationHour.Value)*60 + ...
                        str2double(app.InputDurationMinute.Value);
                    app.MainApp.Import.Data.duration = min([app.MainApp.Import.Data.duration, (app.MainApp.ACT.xmax-app.MainApp.Import.Data.onset)*24*60]);
            end
            % Set the input fields
            setInputs(app)
            % Set new offset value
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            % Show data excerpt in panel
            app_constructDataExcerpt(app, app.DataPanel, app.MainApp.Import.Data.onset, app.MainApp.Import.Data.onset+app.MainApp.Import.Data.duration/(24*60));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditEventUIFigure = uifigure('Visible', 'off');
            app.EditEventUIFigure.Name = 'Edit Event';
            app.GridLayout = uigridlayout(app.EditEventUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.EditButton = uibutton(app.GridLayout, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            app.ModifyEventLabelPanel = uipanel(app.GridLayout);
            app.ModifyEventLabelPanel.Title = 'Modify Event Label';
            app.GridLayoutCreateNewEventPanel = uigridlayout(app.ModifyEventLabelPanel);
            app.EventLabelInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.EventLabelInput.Items = {'Select...', 'Custom Label', 'Reject', 'Sleep Window'};
            app.EventLabelInput.ItemsData = {'select', 'custom', 'reject', 'sleepWindow'};
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Value = 'select';
            app.CustomEventLabelInput = uieditfield(app.GridLayoutCreateNewEventPanel, 'text');
            app.CustomEventLabelInput.ValueChangedFcn = createCallbackFcn(app, @CustomEventLabelInputValueChanged, true);
            app.CustomEventLabelInput.Visible = 'off';
            app.CustomEventLabelInput.Value = 'Custom Event';
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Mon 03/06/2019 HH:MM - Mon 03/06/2019 HH:MM';
            app.ModifyEventOnsetPanel = uipanel(app.GridLayout);
            app.ModifyEventOnsetPanel.Title = 'Modify Event Onset';
            app.GridLayoutEditEventOnsetPanel = uigridlayout(app.ModifyEventOnsetPanel);
            app.OnsetDayLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetDayLabel.Text = 'Day';
            app.OnsetMonthLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetMonthLabel.Text = 'Month';
            app.OnsetYearLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetYearLabel.Text = 'Year';
            app.OnsetHourLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetHourLabel.Text = 'Hour';
            app.OnsetMinuteLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetMinuteLabel.Text = 'Minute';
            app.ButtonOnsetDayUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetDayUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetDayUp.Text = '^';
            app.ButtonOnsetMonthUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMonthUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMonthUp.Text = '^';
            app.ButtonOnsetYearUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetYearUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetYearUp.Text = '^';
            app.ButtonOnsetHourUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetHourUp.Text = '^';
            app.ButtonOnsetMinuteUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMinuteUp.Text = '^';
            app.InputOnsetDay = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetDay.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetMonth = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetMonth.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetYear = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetYear.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetHour = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetMinute = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ButtonOnsetDayDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetDayDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetDayDown.Text = 'v';
            app.ButtonOnsetMonthDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMonthDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMonthDown.Text = 'v';
            app.ButtonOnsetYearDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetYearDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetYearDown.Text = 'v';
            app.ButtonOnsetHourDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetHourDown.Text = 'v';
            app.ButtonOnsetMinuteDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMinuteDown.Text = 'v';
            app.LabelOnsetDaySep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetDaySep.Text = '/';
            app.LabelOnsetMonthSep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetMonthSep.Text = '/';
            app.LabelOnsetHourSep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetHourSep.Text = ':';
            app.ModifyEventDurationPanel = uipanel(app.GridLayout);
            app.ModifyEventDurationPanel.Title = 'Modify Event Duration';
            app.GridLayoutEditEventDurationPanel = uigridlayout(app.ModifyEventDurationPanel);
            app.DurationHourLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.DurationHourLabel.Text = 'h';
            app.ButtonDurationHourUp = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationHourUp.Text = '^';
            app.ButtonDurationMinuteUp = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationMinuteUp.Text = '^';
            app.InputDurationHour = uieditfield(app.GridLayoutEditEventDurationPanel, 'text');
            app.InputDurationHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputDurationMinute = uieditfield(app.GridLayoutEditEventDurationPanel, 'text');
            app.InputDurationMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ButtonDurationHourDown = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationHourDown.Text = 'v';
            app.ButtonDurationMinuteDown = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationMinuteDown.Text = 'v';
            app.LabelDurationHourSep = uilabel(app.GridLayoutEditEventDurationPanel);
            app.LabelDurationHourSep.Text = 'm';
            app.NewOffsetLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.NewOffsetLabel.Text = 'New Offset';
            app.NewEventOffsetValue = uilabel(app.GridLayoutEditEventDurationPanel);
            app.NewEventOffsetValue.Text = '';
            app.CurrentOffsetLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.CurrentOffsetLabel.Text = 'Current Offset';
            app.CurrentEventOffsetValue = uilabel(app.GridLayoutEditEventDurationPanel);
            app.CurrentEventOffsetValue.Text = '';
            app.DeleteEventButton = uibutton(app.GridLayout, 'push');
            app.DeleteEventButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventButtonPushed, true);
            app.DeleteEventButton.Text = 'Delete Event';
            % Show the figure after all components are created
            app.EditEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditEventUIFigure)
        end
    end
end